classdef magnetalignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DisconnectMotorsButton  matlab.ui.control.Button
        InfoTextArea            matlab.ui.control.TextArea
        InfoTextAreaLabel       matlab.ui.control.Label
        ZAxisPanel              matlab.ui.container.Panel
        ZStepUpButton          matlab.ui.control.Button
        ZStepSizeEditField     matlab.ui.control.NumericEditField
        ZStepDownButton        matlab.ui.control.Button
        YStepUpButton          matlab.ui.control.Button
        YStepSizeEditField     matlab.ui.control.NumericEditField
        YStepDownButton        matlab.ui.control.Button
        XStepUpButton          matlab.ui.control.Button
        XStepSizeEditField     matlab.ui.control.NumericEditField
        XStepDownButton        matlab.ui.control.Button
        SpeedEditField_3        matlab.ui.control.NumericEditField
        SpeedEditField_3Label   matlab.ui.control.Label
        EnableCheckBox_3        matlab.ui.control.CheckBox
        EditField_3             matlab.ui.control.NumericEditField
        PtsEditField_3          matlab.ui.control.NumericEditField
        PtsEditField_3Label     matlab.ui.control.Label
        MaxEditField_3          matlab.ui.control.NumericEditField
        MaxEditField_3Label     matlab.ui.control.Label
        MinEditField_3          matlab.ui.control.NumericEditField
        MinEditField_3Label     matlab.ui.control.Label
        YAxisPanel              matlab.ui.container.Panel
        SpeedEditField_2        matlab.ui.control.NumericEditField
        SpeedEditField_2Label   matlab.ui.control.Label
        EnableCheckBox_2        matlab.ui.control.CheckBox
        EditField_2             matlab.ui.control.NumericEditField
        PtsEditField_2          matlab.ui.control.NumericEditField
        PtsEditField_2Label     matlab.ui.control.Label
        MaxEditField_2          matlab.ui.control.NumericEditField
        MaxEditField_2Label     matlab.ui.control.Label
        MinEditField_2          matlab.ui.control.NumericEditField
        MinEditField_2Label     matlab.ui.control.Label
        XAxisPanel              matlab.ui.container.Panel
        SpeedEditField          matlab.ui.control.NumericEditField
        SpeedEditFieldLabel     matlab.ui.control.Label
        EnableCheckBox          matlab.ui.control.CheckBox
        EditField               matlab.ui.control.NumericEditField
        PtsEditField            matlab.ui.control.NumericEditField
        PtsEditFieldLabel       matlab.ui.control.Label
        MaxEditField            matlab.ui.control.NumericEditField
        MaxEditFieldLabel       matlab.ui.control.Label
        MinEditField            matlab.ui.control.NumericEditField
        MinEditFieldLabel       matlab.ui.control.Label
        SettocursorButton       matlab.ui.control.Button
        STOPSCANButton          matlab.ui.control.Button
        STARTSCANButton         matlab.ui.control.Button
        ConnectMotorsButton     matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
                % --- é€»è¾‘å±žæ€§ ---
        Acq                 % ImageAcquisitionThorlabs å¯¹è±¡
        NI                  % CounterAcquisition å¯¹è±¡
        AbortScan = false;  % åœæ­¢æ ‡å¿—ä½
    end
    
    methods (Access = private)
        
        % --- è¾…åŠ©åŠŸèƒ½ ---
        function updatePositions(app)
            % æ›´æ–°ç•Œé¢ä¸Šçš„å®žæ—¶ä½ç½®ï¼ˆè“è‰²èƒŒæ™¯æ¡†ï¼‰
            try
                app.EditField.Value = System.Decimal.ToDouble(app.Acq.Motors.X.Position);
                app.EditField_2.Value = System.Decimal.ToDouble(app.Acq.Motors.Y.Position);
                app.EditField_3.Value = System.Decimal.ToDouble(app.Acq.Motors.Z.Position);
            catch
            end
        end
        function stepAxis(app, axisName, direction)
            try
                switch axisName
                    case 'X'
                        positionField = app.EditField;
                        stepField = app.XStepSizeEditField;
                        speedValue = app.SpeedEditField.Value;
                    case 'Y'
                        positionField = app.EditField_2;
                        stepField = app.YStepSizeEditField;
                        speedValue = app.SpeedEditField_2.Value;
                    case 'Z'
                        positionField = app.EditField_3;
                        stepField = app.ZStepSizeEditField;
                        speedValue = app.SpeedEditField_3.Value;
                    otherwise
                        return;
                end

                stepSize = stepField.Value;
                if isempty(stepSize) || isnan(stepSize) || stepSize <= 0
                    app.InfoTextArea.Value = 'Step size must be positive.';
                    return;
                end

                targetPosition = positionField.Value + direction*stepSize;
                positionField.Value = targetPosition;

                motor = app.Acq.Motors.(axisName);
                if isempty(motor) || ~motor.IsConnected
                    app.InfoTextArea.Value = sprintf('%s target set to %.3f. Motor is not connected.', axisName, targetPosition);
                    return;
                end

                app.Acq.setMotorSpeed(axisName, speedValue);
                motor.MoveTo(System.Decimal(targetPosition), 60000);
                app.updatePositions();
                app.InfoTextArea.Value = sprintf('%s moved to %.3f.', axisName, targetPosition);
            catch ME
                app.InfoTextArea.Value = ['Step move error: ' ME.message];
            end
        end    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, niObj)
            % 1. å°†å¤–éƒ¨ä¼ è¿›æ¥çš„ç¡¬ä»¶å¯¹è±¡èµ‹å€¼ç»™æœ¬ App çš„å±žæ€§
            if nargin > 0
                % 1. åœ¨è¿™é‡Œå»ºç«‹ ImageAcquisitionThorlabs å®žä¾‹
                % è¿™æ · app.Acq å°±ä¸æ˜¯ç©ºçš„äº†ï¼ŒåŽé¢çš„ Connect æŒ‰é’®æ‰èƒ½æ­£å¸¸å·¥ä½œ
                app.Acq = ImageAcquisitionThorlabs();

                app.NI = niObj;
                app.InfoTextArea.Value = 'Hardware initialized. Connect motors when ready.';
            else
                app.InfoTextArea.Value = 'Warning: no input object detected.';
            end
        end

        % Button pushed function: ConnectMotorsButton
        function ConnectMotorsButtonPushed(app, event)
            % --- é€»è¾‘ 1ï¼šè¿žæŽ¥ç”µæœº (å‚è€ƒ thorlabmotortest.m) ---
            app.InfoTextArea.Value = 'Connecting motors...';
            try
 
                % åŠ è½½ Kinesis åº“
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.DeviceManagerCLI.dll');
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.GenericMotorCLI.dll');
                NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.KCube.DCServoCLI.dll');

                import Thorlabs.MotionControl.DeviceManagerCLI.*
                import Thorlabs.MotionControl.GenericMotorCLI.*
                import Thorlabs.MotionControl.KCube.DCServoCLI.*

                DeviceManagerCLI.BuildDeviceList();
                serialNumbers = cell(ToArray(DeviceManagerCLI.GetDeviceList()));
                
                if length(serialNumbers) < 3
                    uialert(app.UIFigure, 'Fewer than 3 motor serial numbers detected.', 'Hardware Error');
                    return;
                end

                % è¿™é‡Œå‡è®¾é¡ºåºä¸Ž thorlabmotortest ä¸€è‡´ï¼šX, Z, Y
                aliases = {'X', 'Z', 'Y'};

                for i = 1:3
                    sn = serialNumbers{i};
                    dev = KCubeDCServo.CreateKCubeDCServo(sn);
                    dev.Connect(sn);
                    dev.WaitForSettingsInitialized(5000);
                    cfg = dev.LoadMotorConfiguration(sn);
                    cfg.DeviceSettingsName = 'MTS25-Z8'; 
                    cfg.UpdateCurrentConfiguration();
                    dev.SetSettings(dev.MotorDeviceSettings, true, false);
                    dev.StartPolling(250);
                    app.Acq.Motors.(aliases{i}) = dev;

                end
                
                app.InfoTextArea.Value = 'Motors connected.';
                app.Acq.setMotorSpeed('X', app.SpeedEditField.Value);
                app.Acq.setMotorSpeed('Y', app.SpeedEditField_2.Value);
                app.Acq.setMotorSpeed('Z', app.SpeedEditField_3.Value);
                app.InfoTextArea.Value = 'Motors connected and speeds configured.';
                app.updatePositions();
            catch ME
                app.InfoTextArea.Value = ['Connection failed: ' ME.message];
            end
        end

        % Button pushed function: DisconnectMotorsButton
        function DisconnectMotorsButtonPushed(app, event)
            app.InfoTextArea.Value = 'Disconnecting motors...';

            % æ£€æŸ¥ Acq å¯¹è±¡åŠ Motors å±žæ€§æ˜¯å¦å­˜åœ¨
            if isempty(app.Acq) || isempty(app.Acq.Motors)
                app.InfoTextArea.Value = 'No connected motors detected.';
                return;
            end

            % å®šä¹‰éœ€è¦æ–­å¼€çš„è½´åˆ«å
            aliases = {'X', 'Y', 'Z'};

            try
                for i = 1:length(aliases)
                    ax = aliases{i};
                    % æ£€æŸ¥è¯¥è½´æ˜¯å¦å·²åˆå§‹åŒ–å¹¶è¿žæŽ¥
                    if ~isempty(app.Acq.Motors.(ax)) && app.Acq.Motors.(ax).IsConnected
                        % 1. åœæ­¢è½®è¯¢ (å‚è€ƒ thorlabmotortest.m)
                        app.Acq.Motors.(ax).StopPolling();

                        % 2. æ–­å¼€è¿žæŽ¥
                        app.Acq.Motors.(ax).Disconnect();

                        % 3. æ¸…ç©ºå¥æŸ„é˜²æ­¢è¯¯æ“ä½œ
                        app.Acq.Motors.(ax) = [];
                    end
                end
                app.InfoTextArea.Value = 'All devices disconnected safely.';
            catch ME
                app.InfoTextArea.Value = ['Disconnect error: ' ME.message];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                % è°ƒç”¨æˆ‘ä»¬ä¹‹å‰å†™å¥½çš„æ–­å¼€è¿žæŽ¥å‡½æ•°
                app.DisconnectMotorsButtonPushed();
            catch
                % é˜²æ­¢æ–­å¼€è¿‡ç¨‹ä¸­æŠ¥é”™å¯¼è‡´çª—å£å…³ä¸æŽ‰
            end

            % 2. æ­£å¸¸é”€æ¯ UI çª—å£

            delete(app)

        end

        % Button pushed function: STARTSCANButton
        function STARTSCANButtonPushed(app, event)
             % --- é€»è¾‘ 2ï¼š2D Zig-Zag æ‰«æ (å‚è€ƒ ImageAcquisitionThorlabs.m) ---
             app.AbortScan = false;
             app.InfoTextArea.Value = 'Scanning...';

             % 1. åŒæ­¥ UI çš„å‚æ•°åˆ° Acq å¯¹è±¡
             app.Acq.CurrentScan.MinValues = [app.MinEditField.Value, app.MinEditField_2.Value, app.MinEditField_3.Value];
             app.Acq.CurrentScan.MaxValues = [app.MaxEditField.Value, app.MaxEditField_2.Value, app.MaxEditField_3.Value];
             app.Acq.CurrentScan.NumPoints = [app.PtsEditField.Value, app.PtsEditField_2.Value, app.PtsEditField_3.Value];
             app.Acq.CurrentScan.bEnable = [app.EnableCheckBox.Value, app.EnableCheckBox_2.Value, app.EnableCheckBox_3.Value];


             app.Acq.setMotorSpeed('X', app.SpeedEditField.Value);
             app.Acq.setMotorSpeed('Y', app.SpeedEditField_2.Value);
             app.Acq.setMotorSpeed('Z', app.SpeedEditField_3.Value);

             % 2. ä¸€é”®å¯åŠ¨æ‰«æ (ä¼ å…¥è®¡æ•°å™¨å¯¹è±¡å’Œ App å¥æŸ„)
             try
                 app.Acq.RunScan(app.NI, app);
                 app.InfoTextArea.Value = 'Scan complete.';
             catch ME
                 app.InfoTextArea.Value = ['Scan error: ' ME.message];
             end

             app.updatePositions();

        end

        % Button pushed function: SettocursorButton
        function SettocursorButtonPushed(app, event)
            % Button pushed function: SettocursorButton
            % 1. æ£€æŸ¥ç”µæœºæ˜¯å¦è¿žæŽ¥
            if isempty(app.Acq) || isempty(app.Acq.Motors)
                uialert(app.UIFigure, 'Motors are not connected. Click Connect Motors first.', 'Operation Failed');
                return;
            end

            app.InfoTextArea.Value = 'Moving motors manually...';

            try
                % 2. ä»Žè“è‰² EditField ä¸­èŽ·å–ç”¨æˆ·è¾“å…¥çš„æ•°å€¼
                targetX = app.EditField.Value;
                targetY = app.EditField_2.Value;
                targetZ = app.EditField_3.Value;

                % 3. è°ƒç”¨ç”µæœºé©±åŠ¨æ‰§è¡Œç§»åŠ¨
                % æ³¨æ„ï¼šThorlabs Kinesis å¿…é¡»ä½¿ç”¨ System.Decimal è½¬æ¢æ•°æ®ç±»åž‹
                if ~isempty(app.Acq.Motors.X)
                    app.Acq.Motors.X.MoveTo(System.Decimal(targetX), 60000); % 60000æ˜¯è¶…æ—¶æ—¶é—´(ms)
                end

                if ~isempty(app.Acq.Motors.Y)
                    app.Acq.Motors.Y.MoveTo(System.Decimal(targetY), 60000);
                end

                if ~isempty(app.Acq.Motors.Z)
                    app.Acq.Motors.Z.MoveTo(System.Decimal(targetZ), 60000);
                end

                % 4. æ›´æ–°çŠ¶æ€æç¤º
                app.InfoTextArea.Value = sprintf('Move complete. Target: X=%.3f, Y=%.3f, Z=%.3f', targetX, targetY, targetZ);

            catch ME
                app.InfoTextArea.Value = ['Move error: ' ME.message];
                uialert(app.UIFigure, ME.message, 'Hardware Error');
            end

        end

        % Button pushed function: STOPSCANButton
        function STOPSCANButtonPushed(app, event)
            % ä¿®æ”¹æ ‡å¿—ä½
            app.AbortScan = true;

            % åœ¨ç•Œé¢æç¤ºç”¨æˆ·
            app.InfoTextArea.Value = 'Stop requested. Please wait...';
            % app.STOPSCANButton.BackgroundColor = [1, 0, 0]; % å˜çº¢è¡¨ç¤ºå·²è§¦å‘
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.ConnectMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectMotorsButtonPushed, true);
            app.ConnectMotorsButton.Text = 'Connect Motors';
            app.STARTSCANButton = uibutton(app.UIFigure, 'push');
            app.STARTSCANButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSCANButtonPushed, true);
            app.STARTSCANButton.Text = 'START SCAN';
            app.STOPSCANButton = uibutton(app.UIFigure, 'push');
            app.STOPSCANButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSCANButtonPushed, true);
            app.STOPSCANButton.Text = 'STOP SCAN';
            app.SettocursorButton = uibutton(app.UIFigure, 'push');
            app.SettocursorButton.ButtonPushedFcn = createCallbackFcn(app, @SettocursorButtonPushed, true);
            app.SettocursorButton.Text = 'Set to cursor';
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X Axis';
            % Create X step controls
            app.XStepDownButton = uibutton(app.XAxisPanel, 'push');
            app.XStepDownButton.ButtonPushedFcn = @(src,event) stepAxis(app, 'X', -1);
            app.XStepDownButton.Text = '-';
            app.XStepSizeEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.XStepSizeEditField.Limits = [0 Inf];
            app.XStepSizeEditField.Value = 0.1;
            app.XStepUpButton = uibutton(app.XAxisPanel, 'push');
            app.XStepUpButton.ButtonPushedFcn = @(src,event) stepAxis(app, 'X', 1);
            app.XStepUpButton.Text = '+';
            app.MinEditFieldLabel = uilabel(app.XAxisPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MinEditField.Limits = [0 24];
            app.MinEditField.Value = 11;
            app.MaxEditFieldLabel = uilabel(app.XAxisPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.MaxEditField.Limits = [10 24];
            app.MaxEditField.Value = 13;
            app.PtsEditFieldLabel = uilabel(app.XAxisPanel);
            app.PtsEditFieldLabel.Text = 'Pts';
            app.PtsEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.PtsEditField.Value = 11;
            app.EditField = uieditfield(app.XAxisPanel, 'numeric');
            app.EditField.Value = 10;
            app.EnableCheckBox = uicheckbox(app.XAxisPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox.Value = true;
            app.SpeedEditFieldLabel = uilabel(app.XAxisPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.XAxisPanel, 'numeric');
            app.SpeedEditField.Value = 0.2;
            app.YAxisPanel = uipanel(app.UIFigure);
            app.YAxisPanel.Title = 'Y Axis';
            % Create Y step controls
            app.YStepDownButton = uibutton(app.YAxisPanel, 'push');
            app.YStepDownButton.ButtonPushedFcn = @(src,event) stepAxis(app, 'Y', -1);
            app.YStepDownButton.Text = '-';
            app.YStepSizeEditField = uieditfield(app.YAxisPanel, 'numeric');
            app.YStepSizeEditField.Limits = [0 Inf];
            app.YStepSizeEditField.Value = 0.1;
            app.YStepUpButton = uibutton(app.YAxisPanel, 'push');
            app.YStepUpButton.ButtonPushedFcn = @(src,event) stepAxis(app, 'Y', 1);
            app.YStepUpButton.Text = '+';
            app.MinEditField_2Label = uilabel(app.YAxisPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MinEditField_2.Limits = [0 24];
            app.MinEditField_2.Value = 10;
            app.MaxEditField_2Label = uilabel(app.YAxisPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.MaxEditField_2.Limits = [0 24];
            app.MaxEditField_2.Value = 12;
            app.PtsEditField_2Label = uilabel(app.YAxisPanel);
            app.PtsEditField_2Label.Text = 'Pts';
            app.PtsEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.PtsEditField_2.Value = 11;
            app.EditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.EditField_2.Value = 10;
            app.EnableCheckBox_2 = uicheckbox(app.YAxisPanel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.EnableCheckBox_2.Value = true;
            app.SpeedEditField_2Label = uilabel(app.YAxisPanel);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.SpeedEditField_2 = uieditfield(app.YAxisPanel, 'numeric');
            app.SpeedEditField_2.Value = 0.2;
            app.ZAxisPanel = uipanel(app.UIFigure);
            app.ZAxisPanel.Title = 'Z Axis';
            % Create Z step controls
            app.ZStepDownButton = uibutton(app.ZAxisPanel, 'push');
            app.ZStepDownButton.ButtonPushedFcn = @(src,event) stepAxis(app, 'Z', -1);
            app.ZStepDownButton.Text = '-';
            app.ZStepSizeEditField = uieditfield(app.ZAxisPanel, 'numeric');
            app.ZStepSizeEditField.Limits = [0 Inf];
            app.ZStepSizeEditField.Value = 0.1;
            app.ZStepUpButton = uibutton(app.ZAxisPanel, 'push');
            app.ZStepUpButton.ButtonPushedFcn = @(src,event) stepAxis(app, 'Z', 1);
            app.ZStepUpButton.Text = '+';
            app.MinEditField_3Label = uilabel(app.ZAxisPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.MinEditField_3.Limits = [0 20];
            app.MinEditField_3.Value = 15;
            app.MaxEditField_3Label = uilabel(app.ZAxisPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.MaxEditField_3.Limits = [0 24];
            app.MaxEditField_3.Value = 17;
            app.PtsEditField_3Label = uilabel(app.ZAxisPanel);
            app.PtsEditField_3Label.Text = 'Pts';
            app.PtsEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.PtsEditField_3.Value = 101;
            app.EditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.EnableCheckBox_3 = uicheckbox(app.ZAxisPanel);
            app.EnableCheckBox_3.Text = 'Enable';
            app.SpeedEditField_3Label = uilabel(app.ZAxisPanel);
            app.SpeedEditField_3Label.Text = 'Speed';
            app.SpeedEditField_3 = uieditfield(app.ZAxisPanel, 'numeric');
            app.SpeedEditField_3.Value = 0.1;
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.DisconnectMotorsButton = uibutton(app.UIFigure, 'push');
            app.DisconnectMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectMotorsButtonPushed, true);
            app.DisconnectMotorsButton.Text = 'Disconnect Motors';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magnetalignment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end